classdef DataProc4HRFLFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AboutHRFLFMDPMenu               matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.Tree
        ReadyLabel                      matlab.ui.control.Label
        OpenDirectoryButton             matlab.ui.control.Button
        ProcessDir                      matlab.ui.control.Label
        MakeDirectoryButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BKGsubTab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        BackgroundsubtractionbyoffsetLabel  matlab.ui.control.Label
        OptionsButtonGroup              matlab.ui.container.ButtonGroup
        BKGOffsetButton                 matlab.ui.control.RadioButton
        LoadMatButton                   matlab.ui.control.RadioButton
        BKGOffsetEditField              matlab.ui.control.NumericEditField
        LOADButton                      matlab.ui.control.Button
        OffsetpathLabel                 matlab.ui.control.Label
        SUBTRACTButton                  matlab.ui.control.Button
        BackgroundsubtractionbyrollingballalgorithmLabel  matlab.ui.control.Label
        LightbackgroundCheckBox         matlab.ui.control.CheckBox
        UseparaboloidrollingCheckBox    matlab.ui.control.CheckBox
        UsepresmoothingCheckBox         matlab.ui.control.CheckBox
        RadiusLabel                     matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        PreviewresultsCheckBox          matlab.ui.control.CheckBox
        SaveRolledImageButton           matlab.ui.control.Button
        PreviewoffsetsubtractionCheckBox  matlab.ui.control.CheckBox
        ACsNTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        NumAperEditFieldLabel           matlab.ui.control.Label
        NumAperEditField                matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        nmLabel                         matlab.ui.control.Label
        OffsetpathLabel_2               matlab.ui.control.Label
        SaveACsNedImageButton           matlab.ui.control.Button
        OffsetsettingsButtonGroup       matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        LoadMatButton_2                 matlab.ui.control.RadioButton
        OffsetEditField                 matlab.ui.control.NumericEditField
        LOADButton_2                    matlab.ui.control.Button
        GainsettingsButtonGroup         matlab.ui.container.ButtonGroup
        ManualButton_2                  matlab.ui.control.RadioButton
        LoadMatButton_3                 matlab.ui.control.RadioButton
        GainEditField                   matlab.ui.control.NumericEditField
        LOADButton_3                    matlab.ui.control.Button
        GainpathLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ACsNLabel                       matlab.ui.control.Label
        LoadfileforbothoffsetandgainButton  matlab.ui.control.Button
        PixelSizeEditFieldLabel         matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.NumericEditField
        nmLabel_2                       matlab.ui.control.Label
        ACsNDenoiseButton               matlab.ui.control.Button
        ShowOriginalImageButton         matlab.ui.control.Button
        TRCONVTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PullOutwithCircleMasksCheckBox  matlab.ui.control.CheckBox
        SetFOVsizeEditFieldLabel        matlab.ui.control.Label
        SetFOVsizeEditField             matlab.ui.control.NumericEditField
        pxsofLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        pixelsLabel                     matlab.ui.control.Label
        CuttingPreviewButton            matlab.ui.control.Button
        CutandSavetheCutImagesButton    matlab.ui.control.Button
        CombineFLFMPSFintoMATFileButton  matlab.ui.control.Button
        ConvertFLFMIMGtoMATFileButton   matlab.ui.control.Button
        RowTLEditFieldLabel             matlab.ui.control.Label
        RowTLEditField                  matlab.ui.control.NumericEditField
        ColTLEditFieldLabel             matlab.ui.control.Label
        ColTLEditField                  matlab.ui.control.NumericEditField
        RowTREditFieldLabel             matlab.ui.control.Label
        RowTREditField                  matlab.ui.control.NumericEditField
        ColTREditFieldLabel             matlab.ui.control.Label
        ColTREditField                  matlab.ui.control.NumericEditField
        RowBtmEditFieldLabel            matlab.ui.control.Label
        RowBtmEditField                 matlab.ui.control.NumericEditField
        ColBtmEditFieldLabel            matlab.ui.control.Label
        ColBtmEditField                 matlab.ui.control.NumericEditField
        PitchSizeLabel                  matlab.ui.control.Label
        PitchSizeEditField              matlab.ui.control.NumericEditField
        pixelsLabel_2                   matlab.ui.control.Label
        GeneratePSFandFLFMmatfilesLabel  matlab.ui.control.Label
        RECONTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PSFFileButton                   matlab.ui.control.Button
        FLFMFileButton                  matlab.ui.control.Button
        PSFFilePathLabel                matlab.ui.control.Label
        FLFMFilePathLabel               matlab.ui.control.Label
        IterNumEditFieldLabel           matlab.ui.control.Label
        IterNumEditField                matlab.ui.control.NumericEditField
        RLDeconvolutionButton           matlab.ui.control.Button
        ReconstructionFileButton        matlab.ui.control.Button
        ReconstructionFilePathLabel     matlab.ui.control.Label
        DISPTab                         matlab.ui.container.Tab
        Tree2                           matlab.ui.container.Tree
        OpenimageDirectoryButton        matlab.ui.control.Button
        MagentaButton                   matlab.ui.control.Button
        GreenButton                     matlab.ui.control.Button
        BlueButton                      matlab.ui.control.Button
        InfernoButton                   matlab.ui.control.Button
        JetButton                       matlab.ui.control.Button
        RainbowButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        initialized = 0;
        selpath = '.'; % Open directory
        savepath = '.'; % Save directory
        %         averageOpt = 'cross';
        preimg = 0.75 * ones(1000,1000);
        imgdatatype = "double";
        maxrow1 = 1;
        maxcol1 = 1;
        maxrow2 = 1;
        maxcol2 = 1;
        maxrow3 = 1;
        maxcol3 = 1;
        ctrrow = 1;
        ctrcol = 1;
        himg = 1;
        cutr = 1;
        boundup = 1;
        bounddw = 1;
        boundlf = 1;
        boundrt = 1;
        
        OffsetMat = [];
        GainMat = [];
    end
    
    methods (Access = public)
        function readylabel_signal(app,msg,isworking)
            app.ReadyLabel.Text = msg;
            if isworking
                app.ReadyLabel.BackgroundColor = [0.85,0.41,0.41];
            else
                app.ReadyLabel.BackgroundColor = [0.52,0.85,0.41];
            end
            drawnow;
        end
        
        function imshow_norm(app,img)
            img = double(img);
            switch app.imgdatatype
                case "uint16"
                    img = uint16(img/max(img(:))*65535);
                case "uint8"
                    img = uint8(img/max(img(:))*255);
                case "double"
                    img = img/max(img(:));            
            end
            app.preimg = img;
            app.himg = imshow(app.preimg,'parent', app.UIAxes);
            drawnow;
        end
        
        function imgout = imtypeconvert(app,img)
            img = double(img);
            switch app.imgdatatype
                case "uint16"
                    imgout = uint16(img);
                case "uint8"
                    imgout = uint8(img);
                case "double"
                    imgout = img;            
            end
        end
        
        function imgout = offsetsubtraction(app)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Subtracting offset...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                app.preimg = double(imread(tifname2open));
                if string(app.OptionsButtonGroup.SelectedObject.Text)=="BKG Offset"
                    app.preimg = (app.preimg - app.OffsetEditField.Value).*double((app.preimg - app.OffsetEditField.Value)>=0);
                    imgout = app.imtypeconvert(app.preimg);
                    app.imshow_norm(app.preimg);
                    app.readylabel_signal('Offset subtraction done!',false);
                else
                    try
                        app.preimg = (app.preimg - app.OffsetMat).*double((app.preimg - app.OffsetMat)>=0);
                        imgout = app.imtypeconvert(app.preimg);
                        app.imshow_norm(app.preimg);
                        app.readylabel_signal('Offset subtraction done!',false);
                    catch ME
                        app.readylabel_signal(ME.message,true);
                    end
                end
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end
        
        function imgout = rollingballsubtraction(app)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Start rolling ball...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                app.preimg = imread(tifname2open);
                app.preimg = BKGsub_rollingball(app.preimg,...
                    app.RadiusEditField.Value,...
                    app.LightbackgroundCheckBox.Value,...
                    app.UseparaboloidrollingCheckBox.Value,...
                    app.UsepresmoothingCheckBox.Value);
                imgout = app.imtypeconvert(app.preimg);
                app.imshow_norm(app.preimg);
                app.readylabel_signal('Rolling ball subtraction done!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end
        
        function show_original_img(app)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('...',true);
                app.preimg = imread( [app.selpath,'/',selectedNodes_text]);
                app.imshow_norm(app.preimg);
                app.readylabel_signal('Showing original image!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.IterNumEditField.Value = 10;
            app.himg = imshow(app.preimg,'parent', app.UIAxes);
            warning off
            
            if string(app.OptionsButtonGroup.SelectedObject.Text) == "BKG Offset"
                app.BKGOffsetEditField.Enable = true;
                app.LOADButton.Enable = false;
                app.OffsetpathLabel.Enable = false;
            else
                app.BKGOffsetEditField.Enable = false;
                app.LOADButton.Enable = true;
                app.OffsetpathLabel.Enable = true;
            end
            
            if string(app.OffsetsettingsButtonGroup.SelectedObject.Text) == "Manual"
                app.OffsetEditField.Enable = true;
                app.LOADButton_2.Enable = false;
                app.OffsetpathLabel_2.Enable = false;
            else
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
            end
            
            if string(app.GainsettingsButtonGroup.SelectedObject.Text) == "Manual"
                app.GainEditField.Enable = true;
                app.LOADButton_3.Enable = false;
                app.GainpathLabel.Enable = false;
            else
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
            end
            
            app.RowTREditField.Value = app.RowTLEditField.Value;
            app.RowBtmEditField.Value = app.RowTLEditField.Value + round(app.PitchSizeEditField.Value/2*sqrt(3));
            app.ColTREditField.Value = app.ColTLEditField.Value + app.PitchSizeEditField.Value;
            app.ColBtmEditField.Value = app.ColTLEditField.Value + round(app.PitchSizeEditField.Value/2);
        end

        % Button pushed function: OpenDirectoryButton
        function OpenDirectoryButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.selpath = 'Z:\Xuanwen\FLFMuf\ExpData';
                    NNN = dir(app.selpath);
                catch
                    app.selpath = '.\';
                end
            end
            selpath0 = uigetdir(app.selpath);
            if length(char(selpath0))~=1
                app.selpath = selpath0;
                delete(app.Tree.Children)
                app.savepath = selpath0;
                try
                    % disp(['Open Directory: ',app.selpath])
                    S = split(app.selpath,'\');
                    uitreepath = uitreenode(app.Tree,'Text',char(S{end}));
                    subfolder = dir(app.selpath);
                    for i = 3:length(subfolder)
                        uitreenode(uitreepath,'Text',subfolder(i).name);
                    end
                    expand(app.Tree,'all')
                catch
                    
                end
            end
            app.initialized = 1;           
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure)
        end

        % Button pushed function: CuttingPreviewButton
        function CuttingPreviewButtonPushed(app, event)
            app.maxrow1 = app.RowTLEditField.Value;
            app.maxcol1 = app.ColTLEditField.Value;
            app.maxrow2 = app.RowTREditField.Value;
            app.maxcol2 = app.ColTREditField.Value;
            app.maxrow3 = app.RowBtmEditField.Value;
            app.maxcol3 = app.ColBtmEditField.Value;
            app.ctrrow = round((app.maxrow1 + app.maxrow2 + app.maxrow3)/3  + (app.maxcol2 - app.maxcol1)/4/sqrt(3));
            app.ctrcol = round((app.maxcol1 + app.maxcol2 + app.maxcol3)/3);
            app.boundup = round(max(app.ctrrow - app.SetFOVsizeEditField.Value/2,1));
            app.bounddw = round(min(app.ctrrow + app.SetFOVsizeEditField.Value/2,str2double(app.Label.Text)));
            app.boundlf = round(max(app.ctrcol - app.SetFOVsizeEditField.Value/2,1));
            app.boundrt = round(min(app.ctrcol + app.SetFOVsizeEditField.Value/2,str2double(app.Label.Text)));
            disp([app.boundup,app.bounddw,app.boundlf,app.boundrt])
            preimgROI = double(app.preimg);
            preimgROI = preimgROI/max(preimgROI(:));
            preimgROI(app.boundup:app.boundup+6,app.boundlf:app.boundrt) = 1.0;
            preimgROI(app.bounddw-6:app.bounddw,app.boundlf:app.boundrt) = 1.0;
            preimgROI(app.boundup:app.bounddw,app.boundlf:app.boundlf+6) = 1.0;
            preimgROI(app.boundup:app.bounddw,app.boundrt-6:app.boundrt) = 1.0;
            app.himg = imshow(preimgROI,'parent', app.UIAxes);
            
            if app.PullOutwithCircleMasksCheckBox.Value
                app.cutr = round(sqrt(min([(app.maxrow1-app.maxrow2)^2 + (app.maxcol1-app.maxcol2)^2,...
                                           (app.maxrow1-app.maxrow3)^2 + (app.maxcol1-app.maxcol3)^2,...
                                           (app.maxrow3-app.maxrow2)^2 + (app.maxcol3-app.maxcol2)^2]))/2);           
                [X,Y] = meshgrid(1:1:size(preimgROI,2),1:1:size(preimgROI,1));
                circle1 = abs(sqrt((X-app.maxcol1).^2 + (Y-app.maxrow1).^2)-app.cutr)<4;
                circle2 = abs(sqrt((X-app.maxcol2).^2 + (Y-app.maxrow2).^2)-app.cutr)<4;
                circle3 = abs(sqrt((X-app.maxcol3).^2 + (Y-app.maxrow3).^2)-app.cutr)<4;
                circles = logical(circle1) | logical(circle2) | logical(circle3);
                preimgROI(circles==1) = 1.0;
                app.himg = imshow(preimgROI,'parent', app.UIAxes);
            end
        end

        % Button pushed function: CutandSavetheCutImagesButton
        function CutandSavetheCutImagesButtonPushed(app, event)
            boundmat = [app.boundup,app.bounddw,app.boundlf,app.boundrt];    
            if app.PullOutwithCircleMasksCheckBox.Value == true
                [X,Y] = meshgrid(1:1:size(app.preimg,2),1:1:size(app.preimg,1));
                circle1 = sqrt((X-app.maxcol1).^2 + (Y-app.maxrow1).^2)<=app.cutr;
                circle2 = sqrt((X-app.maxcol2).^2 + (Y-app.maxrow2).^2)<=app.cutr;
                circle3 = sqrt((X-app.maxcol3).^2 + (Y-app.maxrow3).^2)<=app.cutr;
                circlemat = logical(circle1) | logical(circle2) | logical(circle3);
                CutCircleSave(app.selpath,boundmat,circlemat);
            else
                CutSave(app.selpath,boundmat);
            end
            
        end

        % Button pushed function: ConvertFLFMIMGtoMATFileButton
        function ConvertFLFMIMGtoMATFileButtonPushed(app, event)
            filenames = dir(app.selpath);
            for i = 3:length(filenames)
                if strcmp(filenames(i).name(end-3:end),'.tif')
                    imcut = imread([app.selpath,'\',filenames(i).name]);
                    save([app.selpath,'\',filenames(i).name(1:end-4),'.mat'],"imcut");
                    disp([app.selpath,'\',filenames(i).name(1:end-4),'.mat'])
                end
            end
        end

        % Button pushed function: CombineFLFMPSFintoMATFileButton
        function CombineFLFMPSFintoMATFileButtonPushed(app, event)
            filenames = dir(app.selpath);
            FLFPSF = [];
            for i = 3:length(filenames)
                if strcmp(filenames(i).name(end-3:end),'.tif')
                    im = imread([app.selpath,'\',filenames(i).name]);
                    FLFPSF = cat(3,FLFPSF,im);                  
                end
            end
            save([app.selpath,'\FLFPSF.mat'],"FLFPSF");
            disp([app.selpath,'\FLFPSF.mat'])
        end

        % Button pushed function: PSFFileButton
        function PSFFileButtonPushed(app, event)
            app.readylabel_signal('Acquiring PSF matfile...',true);
            [PSFfile,PSFpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.PSFFilePathLabel.Text =  [PSFpath,PSFfile];
                app.readylabel_signal('PSF matfile acquired!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
            
        end

        % Button pushed function: FLFMFileButton
        function FLFMFileButtonPushed(app, event)
            app.readylabel_signal('Acquiring FLFM image folder...',true);
            FLFMpath = uigetdir(app.selpath);
            try
                app.FLFMFilePathLabel.Text = FLFMpath;
                app.readylabel_signal('FLFM image folder acquired!',false);
            catch ME
              app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: ReconstructionFileButton
        function ReconstructionFileButtonPushed(app, event)
            app.readylabel_signal('Getting reconstruction path...',true);
            Reconpath = uigetdir(app.selpath);
            try
                app.ReconstructionFilePathLabel.Text = Reconpath;
                app.readylabel_signal('Reconstruction path got!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: RLDeconvolutionButton
        function RLDeconvolutionButtonPushed(app, event)
            app.readylabel_signal(['Reconstructing (',num2str(app.IterNumEditField.Value),' iters)...'],true);
            Decon3D(app.PSFFilePathLabel.Text,... 
                    app.FLFMFilePathLabel.Text,...
                    app.ReconstructionFilePathLabel.Text,...
                    app.IterNumEditField.Value);
            app.readylabel_signal('Reconstruction completed! File saved!',false);
        end

        % Button pushed function: OpenimageDirectoryButton
        function OpenimageDirectoryButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.savepath = 'Z:\Xuanwen\FLFMuf\ExpData';
                    NNN = dir(app.savepath);
                catch
                    app.savepath = '.\';
                end
            end
            savepath0 = uigetdir(app.savepath);
            if length(char(savepath0))~=1
                app.savepath = savepath0;
                delete(app.Tree2.Children)
                app.selpath = savepath0;
                try
                    % disp(['Save Directory: ',app.savepath])
                    S = split(app.savepath,'\');
                    uitreepath = uitreenode(app.Tree2,'Text',char(S{end}));
                    subfolder = dir(app.savepath);
                    for i = 3:length(subfolder)
                        uitreenode(uitreepath,'Text',subfolder(i).name);
                    end
                    expand(app.Tree2,'all')
                catch
                    
                end
            end
            app.initialized = 1;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            disp(selectedNodes.Text);
            selectedNodes_text = selectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Loading image...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                app.preimg = imread(tifname2open);
                app.imgdatatype = string(class(app.preimg));
                app.preimg = double(app.preimg);
                app.preimg = app.preimg/max(max(app.preimg));
                app.Label.Text = num2str(min(size(app.preimg,1),size(app.preimg,2)));
                app.himg = imshow(app.preimg,'parent', app.UIAxes);
                app.readylabel_signal(selectedNodes_text,false);
            else
                app.readylabel_signal([selectedNodes_text,' is not an image!'],false);
            end
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "BKG Offset"
                app.BKGOffsetEditField.Enable = true;
                app.LOADButton.Enable = false;
                app.OffsetpathLabel.Enable = false;
            else
                app.BKGOffsetEditField.Enable = false;
                app.LOADButton.Enable = true;
                app.OffsetpathLabel.Enable = true;
            end
        end

        % Value changed function: PreviewoffsetsubtractionCheckBox
        function PreviewoffsetsubtractionCheckBoxValueChanged(app, event)
            value = app.PreviewoffsetsubtractionCheckBox.Value;
            if value
                imgout = app.offsetsubtraction();
            else
               app.show_original_img();
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            app.readylabel_signal('Loading offset file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.OffsetpathLabel.Text =  [Offsetpath,Offsetfile];
                load(app.OffsetpathLabel.Text,'Offset');
                app.OffsetMat = Offset;
                app.readylabel_signal('Offset file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: SUBTRACTButton
        function SUBTRACTButtonPushed(app, event)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                imgout = app.offsetsubtraction();
                app.readylabel_signal('Saving image...',true);
                UpperSave(app.selpath,selectedNodes_text,imgout,'offsetsub');
                app.readylabel_signal('Image saved!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end     
        end

        % Value changed function: PreviewresultsCheckBox
        function PreviewresultsCheckBoxValueChanged(app, event)
            value = app.PreviewresultsCheckBox.Value;
            if value
                app.rollingballsubtraction();
            else
               app.show_original_img();
            end
        end

        % Button pushed function: SaveRolledImageButton
        function SaveRolledImageButtonPushed(app, event)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                imgout = app.rollingballsubtraction();
                app.readylabel_signal('Saving image...',true);
                UpperSave(app.selpath,selectedNodes_text,imgout,'rollingballsub');
                app.readylabel_signal('Image saved!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end     
        end

        % Button pushed function: LoadfileforbothoffsetandgainButton
        function LoadfileforbothoffsetandgainButtonPushed(app, event)
            app.readylabel_signal('Loading camera calibration file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                load([Offsetpath,Offsetfile],'Offset');
                load([Offsetpath,Offsetfile],'Gain');
                app.OffsetMat = Offset;
                app.GainMat = Gain;
                app.readylabel_signal('Camera calibration file loaded!',false);
                app.LoadMatButton_2.Value = true;
                app.LoadMatButton_3.Value = true;
                
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
                
                app.OffsetpathLabel_2.Text = [Offsetpath,Offsetfile];
                app.GainpathLabel.Text = [Offsetpath,Offsetfile];
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Selection changed function: OffsetsettingsButtonGroup
        function OffsetsettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OffsetsettingsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "Manual"
                app.OffsetEditField.Enable = true;
                app.LOADButton_2.Enable = false;
                app.OffsetpathLabel_2.Enable = false;
            else
                app.OffsetMat = [];
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
            end
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
            app.readylabel_signal('Loading offset file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.OffsetpathLabel_2.Text =  [Offsetpath,Offsetfile];
                load(app.OffsetpathLabel_2.Text,'Offset');
                app.OffsetMat = Offset;
                app.readylabel_signal('Offset file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Selection changed function: GainsettingsButtonGroup
        function GainsettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.GainsettingsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "Manual"
                app.GainEditField.Enable = true;
                app.LOADButton_3.Enable = false;
                app.GainpathLabel.Enable = false;
            else
                app.GainMat = [];
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
            end
        end

        % Button pushed function: LOADButton_3
        function LOADButton_3Pushed(app, event)
            app.readylabel_signal('Loading gain file...',true);
            [Gainfile,Gainpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.GainpathLabel.Text =  [Gainpath,Gainfile];
                load(app.GainpathLabel.Text,'Gain');
                app.GainMat = Gain;
                app.readylabel_signal('Gain file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: ACsNDenoiseButton
        function ACsNDenoiseButtonPushed(app, event)
            addpath(".\ACsN\");
            addpath(".\ACsN\ACsN_code\");
            addpath(".\ACsN\ACsN_code\Functions");
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Denoising image with ACsN algorithm...',true);
                try
                    raw = double(loadtiff([app.selpath,'/',selectedNodes_text]));
                    % -------------------------------------------------------
                    if string(app.OffsetsettingsButtonGroup.SelectedObject.Text) == "Manual"
                        app.OffsetMat = app.OffsetEditField.Value;
                    end                    
                    if string(app.GainsettingsButtonGroup.SelectedObject.Text) == "Manual"
                        app.GainMat = app.GainEditField.Value;
                    end
                    % -------------------------------------------------------
                    acsn  = ACSN(raw,app.NumAperEditField.Value,...
                        app.WavelengthEditField.Value,...
                        app.PixelSizeEditField.Value,...
                        'Offset',app.OffsetMat,'Gain',app.GainMat);
                    app.imshow_norm(acsn);
                    app.preimg = app.imtypeconvert(acsn);
                    app.SaveACsNedImageButton.Enable = true;
                    app.readylabel_signal('ACsN Denoising completed!',false);
                catch ME
                    app.readylabel_signal(ME.message,true);
                end
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
            
        end

        % Button pushed function: SaveACsNedImageButton
        function SaveACsNedImageButtonPushed(app, event)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Saving image...',true);
                UpperSave(app.selpath,selectedNodes_text,app.preimg,'acsn');
                app.readylabel_signal('Image saved!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end

        % Button pushed function: ShowOriginalImageButton
        function ShowOriginalImageButtonPushed(app, event)
            app.show_original_img();
            app.SaveACsNedImageButton.Enable = false;
        end

        % Value changed function: RowTLEditField
        function RowTLEditFieldValueChanged(app, event)
            value = app.RowTLEditField.Value;
            app.RowTREditField.Value = value;
            app.RowBtmEditField.Value = value + round(app.PitchSizeEditField.Value/2*sqrt(3));
        end

        % Value changed function: ColTLEditField
        function ColTLEditFieldValueChanged(app, event)
            value = app.ColTLEditField.Value;
            app.ColTREditField.Value = value + app.PitchSizeEditField.Value;
            app.ColBtmEditField.Value = value + round(app.PitchSizeEditField.Value/2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure = uifigure('Visible', 'off');
            app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure.Name = 'High Resolution Fourier Light Field Microscopy Data Processing';
            app.FileMenu = uimenu(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure);
            app.EditMenu.Text = 'Edit';
            app.AboutMenu = uimenu(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutHRFLFMDPMenu = uimenu(app.AboutMenu);
            app.AboutHRFLFMDPMenu.Text = 'About HRFLFMDP...';
            app.GridLayout = uigridlayout(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure);
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready!';
            app.OpenDirectoryButton = uibutton(app.GridLayout, 'push');
            app.OpenDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirectoryButtonPushed, true);
            app.OpenDirectoryButton.Text = 'Open Directory';
            app.ProcessDir = uilabel(app.GridLayout);
            app.ProcessDir.Text = '';
            app.MakeDirectoryButton = uibutton(app.GridLayout, 'push');
            app.MakeDirectoryButton.Text = 'Make Directory';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BKGsubTab = uitab(app.TabGroup);
            app.BKGsubTab.Title = 'BKGsub';
            app.GridLayout5 = uigridlayout(app.BKGsubTab);
            app.BackgroundsubtractionbyoffsetLabel = uilabel(app.GridLayout5);
            app.BackgroundsubtractionbyoffsetLabel.Text = ' Background subtraction by offset';
            app.OptionsButtonGroup = uibuttongroup(app.GridLayout5);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.BKGOffsetButton = uiradiobutton(app.OptionsButtonGroup);
            app.BKGOffsetButton.Text = 'BKG Offset';
            app.BKGOffsetButton.Value = true;
            app.LoadMatButton = uiradiobutton(app.OptionsButtonGroup);
            app.LoadMatButton.Text = 'Load Mat';
            app.BKGOffsetEditField = uieditfield(app.OptionsButtonGroup, 'numeric');
            app.BKGOffsetEditField.Value = 100;
            app.LOADButton = uibutton(app.OptionsButtonGroup, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.OffsetpathLabel = uilabel(app.GridLayout5);
            app.OffsetpathLabel.Text = 'Offset path';
            app.SUBTRACTButton = uibutton(app.GridLayout5, 'push');
            app.SUBTRACTButton.ButtonPushedFcn = createCallbackFcn(app, @SUBTRACTButtonPushed, true);
            app.SUBTRACTButton.Text = 'SUBTRACT';
            app.BackgroundsubtractionbyrollingballalgorithmLabel = uilabel(app.GridLayout5);
            app.BackgroundsubtractionbyrollingballalgorithmLabel.Text = ' Background subtraction by rolling ball algorithm';
            app.LightbackgroundCheckBox = uicheckbox(app.GridLayout5);
            app.LightbackgroundCheckBox.Text = 'Light background';
            app.UseparaboloidrollingCheckBox = uicheckbox(app.GridLayout5);
            app.UseparaboloidrollingCheckBox.Text = 'Use paraboloid rolling';
            app.UsepresmoothingCheckBox = uicheckbox(app.GridLayout5);
            app.UsepresmoothingCheckBox.Text = 'Use presmoothing';
            app.RadiusLabel = uilabel(app.GridLayout5);
            app.RadiusLabel.Text = 'Radius:';
            app.RadiusEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RadiusEditField.Value = 10;
            app.PreviewresultsCheckBox = uicheckbox(app.GridLayout5);
            app.PreviewresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewresultsCheckBoxValueChanged, true);
            app.PreviewresultsCheckBox.Text = 'Preview results';
            app.SaveRolledImageButton = uibutton(app.GridLayout5, 'push');
            app.SaveRolledImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRolledImageButtonPushed, true);
            app.SaveRolledImageButton.Text = 'Save Rolled Image';
            app.PreviewoffsetsubtractionCheckBox = uicheckbox(app.GridLayout5);
            app.PreviewoffsetsubtractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewoffsetsubtractionCheckBoxValueChanged, true);
            app.PreviewoffsetsubtractionCheckBox.Text = 'Preview offset subtraction';
            app.ACsNTab = uitab(app.TabGroup);
            app.ACsNTab.Title = 'ACsN';
            app.GridLayout4 = uigridlayout(app.ACsNTab);
            app.NumAperEditFieldLabel = uilabel(app.GridLayout4);
            app.NumAperEditFieldLabel.Text = 'Num. Aper.';
            app.NumAperEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumAperEditField.Value = 1.45;
            app.WavelengthEditFieldLabel = uilabel(app.GridLayout4);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WavelengthEditField.Value = 680;
            app.nmLabel = uilabel(app.GridLayout4);
            app.nmLabel.Text = ' nm';
            app.OffsetpathLabel_2 = uilabel(app.GridLayout4);
            app.OffsetpathLabel_2.Text = 'Offset path';
            app.SaveACsNedImageButton = uibutton(app.GridLayout4, 'push');
            app.SaveACsNedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveACsNedImageButtonPushed, true);
            app.SaveACsNedImageButton.Enable = 'off';
            app.SaveACsNedImageButton.Text = 'Save ACsNed Image';
            app.OffsetsettingsButtonGroup = uibuttongroup(app.GridLayout4);
            app.OffsetsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OffsetsettingsButtonGroupSelectionChanged, true);
            app.OffsetsettingsButtonGroup.Title = 'Offset settings';
            app.ManualButton = uiradiobutton(app.OffsetsettingsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.LoadMatButton_2 = uiradiobutton(app.OffsetsettingsButtonGroup);
            app.LoadMatButton_2.Text = 'Load Mat';
            app.OffsetEditField = uieditfield(app.OffsetsettingsButtonGroup, 'numeric');
            app.OffsetEditField.Value = 100;
            app.LOADButton_2 = uibutton(app.OffsetsettingsButtonGroup, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.GainsettingsButtonGroup = uibuttongroup(app.GridLayout4);
            app.GainsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GainsettingsButtonGroupSelectionChanged, true);
            app.GainsettingsButtonGroup.Title = 'Gain settings';
            app.ManualButton_2 = uiradiobutton(app.GainsettingsButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.LoadMatButton_3 = uiradiobutton(app.GainsettingsButtonGroup);
            app.LoadMatButton_3.Text = 'Load Mat';
            app.GainEditField = uieditfield(app.GainsettingsButtonGroup, 'numeric');
            app.GainEditField.Value = 1;
            app.LOADButton_3 = uibutton(app.GainsettingsButtonGroup, 'push');
            app.LOADButton_3.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_3Pushed, true);
            app.LOADButton_3.Text = 'LOAD';
            app.GainpathLabel = uilabel(app.GridLayout4);
            app.GainpathLabel.Text = 'Gain path';
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'acsn.jpg';
            app.ACsNLabel = uilabel(app.GridLayout4);
            app.ACsNLabel.Text = 'ACsN';
            app.LoadfileforbothoffsetandgainButton = uibutton(app.GridLayout4, 'push');
            app.LoadfileforbothoffsetandgainButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileforbothoffsetandgainButtonPushed, true);
            app.LoadfileforbothoffsetandgainButton.Text = 'Load file for both offset and gain...';
            app.PixelSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PixelSizeEditField.Value = 65;
            app.nmLabel_2 = uilabel(app.GridLayout4);
            app.nmLabel_2.Text = ' nm';
            app.ACsNDenoiseButton = uibutton(app.GridLayout4, 'push');
            app.ACsNDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ACsNDenoiseButtonPushed, true);
            app.ACsNDenoiseButton.Text = 'ACsN Denoise';
            app.ShowOriginalImageButton = uibutton(app.GridLayout4, 'push');
            app.ShowOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOriginalImageButtonPushed, true);
            app.ShowOriginalImageButton.Text = 'Show Original Image';
            app.TRCONVTab = uitab(app.TabGroup);
            app.TRCONVTab.Title = 'TR & CONV';
            app.GridLayout3 = uigridlayout(app.TRCONVTab);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.PullOutwithCircleMasksCheckBox = uicheckbox(app.GridLayout3);
            app.PullOutwithCircleMasksCheckBox.Text = '  Pull Out with Circle Masks';
            app.SetFOVsizeEditFieldLabel = uilabel(app.GridLayout3);
            app.SetFOVsizeEditFieldLabel.Text = 'Set FOV size: ';
            app.SetFOVsizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SetFOVsizeEditField.Value = 100;
            app.pxsofLabel = uilabel(app.GridLayout3);
            app.pxsofLabel.Text = 'pxs of';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '1000';
            app.pixelsLabel = uilabel(app.GridLayout3);
            app.pixelsLabel.Text = 'pixels';
            app.CuttingPreviewButton = uibutton(app.GridLayout3, 'push');
            app.CuttingPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CuttingPreviewButtonPushed, true);
            app.CuttingPreviewButton.Text = 'Cutting Preview';
            app.CutandSavetheCutImagesButton = uibutton(app.GridLayout3, 'push');
            app.CutandSavetheCutImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CutandSavetheCutImagesButtonPushed, true);
            app.CutandSavetheCutImagesButton.Text = 'Cut and Save the Cut Images';
            app.CombineFLFMPSFintoMATFileButton = uibutton(app.GridLayout3, 'push');
            app.CombineFLFMPSFintoMATFileButton.ButtonPushedFcn = createCallbackFcn(app, @CombineFLFMPSFintoMATFileButtonPushed, true);
            app.CombineFLFMPSFintoMATFileButton.Text = 'Combine FLFM PSF into MAT File';
            app.ConvertFLFMIMGtoMATFileButton = uibutton(app.GridLayout3, 'push');
            app.ConvertFLFMIMGtoMATFileButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertFLFMIMGtoMATFileButtonPushed, true);
            app.ConvertFLFMIMGtoMATFileButton.Text = 'Convert FLFM IMG to MAT File';
            app.RowTLEditFieldLabel = uilabel(app.GridLayout3);
            app.RowTLEditFieldLabel.Text = 'RowTL';
            app.RowTLEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowTLEditField.ValueChangedFcn = createCallbackFcn(app, @RowTLEditFieldValueChanged, true);
            app.RowTLEditField.Value = 763;
            app.ColTLEditFieldLabel = uilabel(app.GridLayout3);
            app.ColTLEditFieldLabel.Text = 'ColTL';
            app.ColTLEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColTLEditField.ValueChangedFcn = createCallbackFcn(app, @ColTLEditFieldValueChanged, true);
            app.ColTLEditField.Value = 709;
            app.RowTREditFieldLabel = uilabel(app.GridLayout3);
            app.RowTREditFieldLabel.Text = 'RowTR';
            app.RowTREditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowTREditField.Editable = 'off';
            app.ColTREditFieldLabel = uilabel(app.GridLayout3);
            app.ColTREditFieldLabel.Text = 'ColTR';
            app.ColTREditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColTREditField.Editable = 'off';
            app.RowBtmEditFieldLabel = uilabel(app.GridLayout3);
            app.RowBtmEditFieldLabel.Text = 'RowBtm';
            app.RowBtmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowBtmEditField.Editable = 'off';
            app.ColBtmEditFieldLabel = uilabel(app.GridLayout3);
            app.ColBtmEditFieldLabel.Text = 'ColBtm';
            app.ColBtmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColBtmEditField.Editable = 'off';
            app.PitchSizeLabel = uilabel(app.GridLayout3);
            app.PitchSizeLabel.Text = 'PitchSize';
            app.PitchSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PitchSizeEditField.Value = 500;
            app.pixelsLabel_2 = uilabel(app.GridLayout3);
            app.pixelsLabel_2.Text = 'pixels';
            app.GeneratePSFandFLFMmatfilesLabel = uilabel(app.GridLayout3);
            app.GeneratePSFandFLFMmatfilesLabel.Text = 'Generate PSF and FLFM matfiles';
            app.RECONTab = uitab(app.TabGroup);
            app.RECONTab.Title = 'RECON';
            app.GridLayout2 = uigridlayout(app.RECONTab);
            app.PSFFileButton = uibutton(app.GridLayout2, 'push');
            app.PSFFileButton.ButtonPushedFcn = createCallbackFcn(app, @PSFFileButtonPushed, true);
            app.PSFFileButton.Text = 'PSF File';
            app.FLFMFileButton = uibutton(app.GridLayout2, 'push');
            app.FLFMFileButton.ButtonPushedFcn = createCallbackFcn(app, @FLFMFileButtonPushed, true);
            app.FLFMFileButton.Text = 'FLFM File';
            app.PSFFilePathLabel = uilabel(app.GridLayout2);
            app.PSFFilePathLabel.Text = 'PSF File Path';
            app.FLFMFilePathLabel = uilabel(app.GridLayout2);
            app.FLFMFilePathLabel.Text = 'FLFM File Path';
            app.IterNumEditFieldLabel = uilabel(app.GridLayout2);
            app.IterNumEditFieldLabel.Text = 'Iter. Num.';
            app.IterNumEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IterNumEditField.Limits = [1 100];
            app.IterNumEditField.RoundFractionalValues = 'on';
            app.IterNumEditField.Value = 10;
            app.RLDeconvolutionButton = uibutton(app.GridLayout2, 'push');
            app.RLDeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @RLDeconvolutionButtonPushed, true);
            app.RLDeconvolutionButton.Text = 'RL Deconvolution';
            app.ReconstructionFileButton = uibutton(app.GridLayout2, 'push');
            app.ReconstructionFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionFileButtonPushed, true);
            app.ReconstructionFileButton.Text = 'Reconstruction File';
            app.ReconstructionFilePathLabel = uilabel(app.GridLayout2);
            app.ReconstructionFilePathLabel.Text = 'Reconstruction File Path';
            app.DISPTab = uitab(app.TabGroup);
            app.DISPTab.Title = 'DISP';
            app.Tree2 = uitree(app.DISPTab);
            app.OpenimageDirectoryButton = uibutton(app.DISPTab, 'push');
            app.OpenimageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimageDirectoryButtonPushed, true);
            app.OpenimageDirectoryButton.Text = 'Open image Directory';
            app.MagentaButton = uibutton(app.GridLayout, 'push');
            app.MagentaButton.Text = 'Magenta';
            app.GreenButton = uibutton(app.GridLayout, 'push');
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.GridLayout, 'push');
            app.BlueButton.Text = 'Blue';
            app.InfernoButton = uibutton(app.GridLayout, 'push');
            app.InfernoButton.Text = 'Inferno';
            app.JetButton = uibutton(app.GridLayout, 'push');
            app.JetButton.Text = 'Jet';
            app.RainbowButton = uibutton(app.GridLayout, 'push');
            app.RainbowButton.Text = 'Rainbow';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataProc4HRFLFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HighResolutionFourierLightFieldMicroscopyDataProcessingUIFigure)
        end
    end
end