classdef ManualAli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        YRowLabel               matlab.ui.control.Label
        XColLabel               matlab.ui.control.Label
        IncreasedYButton        matlab.ui.control.Button
        DecreasedYButton        matlab.ui.control.Button
        IncreasedXButton        matlab.ui.control.Button
        DecreasedXButton        matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button                  matlab.ui.control.RadioButton
        Button_2                matlab.ui.control.RadioButton
        Button_3                matlab.ui.control.RadioButton
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        SimulatedPSFLabel       matlab.ui.control.Label
        ExperimentalPSFLabel    matlab.ui.control.Label
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
        UIAxes_9                matlab.ui.control.UIAxes
        OverlappingViewLabel    matlab.ui.control.Label
        ShowLayerButton         matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        PlotDisplacementButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp = []; % Description
        imsim1 = [];
        imsim2 = [];
        imsim3 = [];
        imexp1 = [];
        imexp2 = [];
        imexp3 = [];
        imsim_active = [];
        imexp_active = [];
        dy1 = 0;
        dx1 = 0;
        dy2 = 0;
        dx2 = 0;
        dy3 = 0;
        dx3 = 0;
        Ind = (45000:100:55000)';
        t_dy1 = zeros(101,1);
        t_dx1 = zeros(101,1);
        t_dy2 = zeros(101,1);
        t_dx2 = zeros(101,1);
        t_dy3 = zeros(101,1);
        t_dx3 = zeros(101,1);
        currentInd = 0;
    end
    
    methods (Access = private)
        
        function app = tableUpdate(app)
            app.t_dy1(app.Ind==app.currentInd) = app.dy1;
            app.t_dx1(app.Ind==app.currentInd) = app.dx1;
            app.t_dy2(app.Ind==app.currentInd) = app.dy2;
            app.t_dx2(app.Ind==app.currentInd) = app.dx2;
            app.t_dy3(app.Ind==app.currentInd) = app.dy3;
            app.t_dx3(app.Ind==app.currentInd) = app.dx3;
            app.UITable.Data = [app.Ind,app.t_dy1,app.t_dx1,app.t_dy2,app.t_dx2,app.t_dy3,app.t_dx3];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, imsim, imexp, ROIboth)
            app.CallingApp = mainapp; 
            app.imsim1 = imsim(ROIboth(1,1):ROIboth(1,3),ROIboth(1,2):ROIboth(1,4));
            app.imsim2 = imsim(ROIboth(2,1):ROIboth(2,3),ROIboth(2,2):ROIboth(2,4));
            app.imsim3 = imsim(ROIboth(3,1):ROIboth(3,3),ROIboth(3,2):ROIboth(3,4));
            app.imexp1 = imexp(ROIboth(1,1):ROIboth(1,3),ROIboth(1,2):ROIboth(1,4));
            app.imexp2 = imexp(ROIboth(2,1):ROIboth(2,3),ROIboth(2,2):ROIboth(2,4));
            app.imexp3 = imexp(ROIboth(3,1):ROIboth(3,3),ROIboth(3,2):ROIboth(3,4));
            
            try
                for ii = app.CallingApp.alignind'
                    app.t_dy1(app.Ind==ii) = app.CallingApp.dy1(app.CallingApp.alignind'==ii);
                    app.t_dx1(app.Ind==ii) = app.CallingApp.dx1(app.CallingApp.alignind'==ii);
                    app.t_dy2(app.Ind==ii) = app.CallingApp.dy2(app.CallingApp.alignind'==ii);
                    app.t_dx2(app.Ind==ii) = app.CallingApp.dx2(app.CallingApp.alignind'==ii);
                    app.t_dy3(app.Ind==ii) = app.CallingApp.dy3(app.CallingApp.alignind'==ii);
                    app.t_dx3(app.Ind==ii) = app.CallingApp.dx3(app.CallingApp.alignind'==ii);
                end
            catch ME
                rethrow(ME)
            end
            app.UITable.Data = [app.Ind,app.t_dy1,app.t_dx1,app.t_dy2,app.t_dx2,app.t_dy3,app.t_dx3];
            app.currentInd = app.CallingApp.SimExpInd(1);
            
            app.dy1 = app.t_dy1(app.Ind==app.currentInd);
            app.dx1 = app.t_dx1(app.Ind==app.currentInd);
            app.dy2 = app.t_dy2(app.Ind==app.currentInd);
            app.dx2 = app.t_dx2(app.Ind==app.currentInd);
            app.dy3 = app.t_dy3(app.Ind==app.currentInd);
            app.dx3 = app.t_dx3(app.Ind==app.currentInd);
            
            app.imsim1 = circshift(app.imsim1,[app.t_dy1(app.Ind==app.currentInd),app.t_dx1(app.Ind==app.currentInd)]);
            app.imsim2 = circshift(app.imsim2,[app.t_dy2(app.Ind==app.currentInd),app.t_dx2(app.Ind==app.currentInd)]);
            app.imsim3 = circshift(app.imsim3,[app.t_dy3(app.Ind==app.currentInd),app.t_dx3(app.Ind==app.currentInd)]);
            
            imagesc(app.imsim1,'Parent',app.UIAxes);
            imagesc(app.imsim2,'Parent',app.UIAxes_3);
            imagesc(app.imsim3,'Parent',app.UIAxes_5);
            imagesc(app.imexp1,'Parent',app.UIAxes_2);
            imagesc(app.imexp2,'Parent',app.UIAxes_4);
            imagesc(app.imexp3,'Parent',app.UIAxes_6);
            imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            app.YRowLabel.Text = num2str(app.dy1);
            app.XColLabel.Text = num2str(app.dx1);
            
%             disp([app.Ind,app.t_dy1,app.t_dx1,app.t_dy2,app.t_dx2,app.t_dy3,app.t_dx3])
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
            if app.Button.Value==true
                app.YRowLabel.Text = num2str(app.dy1);
                app.XColLabel.Text = num2str(app.dx1);
%                 app.imsim_active = app.imsim1;
%                 app.imexp_active = app.imexp1;
            elseif app.Button_2.Value==true
                app.YRowLabel.Text = num2str(app.dy2);
                app.XColLabel.Text = num2str(app.dx2);
%                 app.imsim_active = app.imsim2;
%                 app.imexp_active = app.imexp2;
            elseif app.Button_3.Value==true
                app.YRowLabel.Text = num2str(app.dy3);
                app.XColLabel.Text = num2str(app.dx3);
%                 app.imsim_active = app.imsim3;
%                 app.imexp_active = app.imexp3;
            end
        end

        % Button pushed function: DecreasedYButton
        function DecreasedYButtonPushed(app, event)
            if app.Button.Value==true
                app.imsim1 = circshift(app.imsim1,-1,1);
                app.dy1 = app.dy1-1;
                app.YRowLabel.Text = num2str(app.dy1);
                imagesc(app.imsim1,'Parent',app.UIAxes);
                imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            elseif app.Button_2.Value==true                
                app.imsim2 = circshift(app.imsim2,-1,1);
                app.dy2 = app.dy2-1;
                app.YRowLabel.Text = num2str(app.dy2);
                imagesc(app.imsim2,'Parent',app.UIAxes_3);
                imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            elseif app.Button_3.Value==true
                app.imsim3 = circshift(app.imsim3,-1,1);
                app.dy3 = app.dy3-1;
                app.YRowLabel.Text = num2str(app.dy3);
                imagesc(app.imsim3,'Parent',app.UIAxes_5);
                imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            end
            tableUpdate(app);
        end

        % Button pushed function: IncreasedYButton
        function IncreasedYButtonPushed(app, event)
            if app.Button.Value==true
                app.imsim1 = circshift(app.imsim1,1,1);
                app.dy1 = app.dy1+1;
                app.YRowLabel.Text = num2str(app.dy1);
                imagesc(app.imsim1,'Parent',app.UIAxes);
                imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            elseif app.Button_2.Value==true                
                app.imsim2 = circshift(app.imsim2,1,1);
                app.dy2 = app.dy2+1;
                app.YRowLabel.Text = num2str(app.dy2);
                imagesc(app.imsim2,'Parent',app.UIAxes_3);
                imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            elseif app.Button_3.Value==true
                app.imsim3 = circshift(app.imsim3,1,1);
                app.dy3 = app.dy3+1;
                app.YRowLabel.Text = num2str(app.dy3);
                imagesc(app.imsim3,'Parent',app.UIAxes_5);
                imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            end
            tableUpdate(app);
        end

        % Button pushed function: DecreasedXButton
        function DecreasedXButtonPushed(app, event)
            if app.Button.Value==true
                app.imsim1 = circshift(app.imsim1,-1,2);
                app.dx1 = app.dx1-1;
                app.XColLabel.Text = num2str(app.dx1);
                imagesc(app.imsim1,'Parent',app.UIAxes);
                imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            elseif app.Button_2.Value==true                
                app.imsim2 = circshift(app.imsim2,-1,2);
                app.dx2 = app.dx2-1;
                app.XColLabel.Text = num2str(app.dx2);
                imagesc(app.imsim2,'Parent',app.UIAxes_3);
                imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            elseif app.Button_3.Value==true
                app.imsim3 = circshift(app.imsim3,-1,2);
                app.dx3 = app.dx3-1;
                app.XColLabel.Text = num2str(app.dx3);
                imagesc(app.imsim3,'Parent',app.UIAxes_5);
                imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            end
            tableUpdate(app);
        end

        % Button pushed function: IncreasedXButton
        function IncreasedXButtonPushed(app, event)
            if app.Button.Value==true
                app.imsim1 = circshift(app.imsim1,1,2);
                app.dx1 = app.dx1+1;
                app.XColLabel.Text = num2str(app.dx1);
                imagesc(app.imsim1,'Parent',app.UIAxes);
                imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            elseif app.Button_2.Value==true                
                app.imsim2 = circshift(app.imsim2,1,2);
                app.dx2 = app.dx2+1;
                app.XColLabel.Text = num2str(app.dx2);
                imagesc(app.imsim2,'Parent',app.UIAxes_3);
                imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            elseif app.Button_3.Value==true
                app.imsim3 = circshift(app.imsim3,1,2);
                app.dx3 = app.dx3+1;
                app.XColLabel.Text = num2str(app.dx3);
                imagesc(app.imsim3,'Parent',app.UIAxes_5);
                imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            end
            tableUpdate(app);
        end

        % Button pushed function: ShowLayerButton
        function ShowLayerButtonPushed(app, event)
            imsim = app.CallingApp.preimg;
            imexp = app.CallingApp.preimg2;
            ROIboth = app.CallingApp.ROIboth;
            app.imsim1 = imsim(ROIboth(1,1):ROIboth(1,3),ROIboth(1,2):ROIboth(1,4));
            app.imsim2 = imsim(ROIboth(2,1):ROIboth(2,3),ROIboth(2,2):ROIboth(2,4));
            app.imsim3 = imsim(ROIboth(3,1):ROIboth(3,3),ROIboth(3,2):ROIboth(3,4));
            app.imexp1 = imexp(ROIboth(1,1):ROIboth(1,3),ROIboth(1,2):ROIboth(1,4));
            app.imexp2 = imexp(ROIboth(2,1):ROIboth(2,3),ROIboth(2,2):ROIboth(2,4));
            app.imexp3 = imexp(ROIboth(3,1):ROIboth(3,3),ROIboth(3,2):ROIboth(3,4));
            
            app.currentInd = app.CallingApp.SimExpInd(1);
            app.dy1 = app.t_dy1(app.Ind==app.currentInd);
            app.dx1 = app.t_dx1(app.Ind==app.currentInd);
            app.dy2 = app.t_dy2(app.Ind==app.currentInd);
            app.dx2 = app.t_dx2(app.Ind==app.currentInd);
            app.dy3 = app.t_dy3(app.Ind==app.currentInd);
            app.dx3 = app.t_dx3(app.Ind==app.currentInd);
            if app.Button.Value==true
                app.YRowLabel.Text = num2str(app.dy1);
                app.XColLabel.Text = num2str(app.dx1);
            elseif app.Button_2.Value==true                
                app.YRowLabel.Text = num2str(app.dy2);
                app.XColLabel.Text = num2str(app.dx2);
            elseif app.Button_3.Value==true
                app.YRowLabel.Text = num2str(app.dy3);
                app.XColLabel.Text = num2str(app.dx3);
            end    
            
            app.imsim1 = circshift(app.imsim1,[app.t_dy1(app.Ind==app.currentInd),app.t_dx1(app.Ind==app.currentInd)]);
            app.imsim2 = circshift(app.imsim2,[app.t_dy2(app.Ind==app.currentInd),app.t_dx2(app.Ind==app.currentInd)]);
            app.imsim3 = circshift(app.imsim3,[app.t_dy3(app.Ind==app.currentInd),app.t_dx3(app.Ind==app.currentInd)]);
            
            imagesc(app.imsim1,'Parent',app.UIAxes);
            imagesc(app.imsim2,'Parent',app.UIAxes_3);
            imagesc(app.imsim3,'Parent',app.UIAxes_5);
            imagesc(app.imexp1,'Parent',app.UIAxes_2);
            imagesc(app.imexp2,'Parent',app.UIAxes_4);
            imagesc(app.imexp3,'Parent',app.UIAxes_6);
            imshowpair(app.imsim1/max(app.imsim1(:)),app.imexp1/max(app.imexp1(:)),'Parent',app.UIAxes_7)
            imshowpair(app.imsim2/max(app.imsim2(:)),app.imexp2/max(app.imexp2(:)),'Parent',app.UIAxes_8)
            imshowpair(app.imsim3/max(app.imsim3(:)),app.imexp3/max(app.imexp3(:)),'Parent',app.UIAxes_9)
            
            
        end

        % Button pushed function: PlotDisplacementButton
        function PlotDisplacementButtonPushed(app, event)
            AliDisp(app,app.Ind,app.t_dx1,app.t_dy1,app.t_dx2,app.t_dy2,app.t_dx3,app.t_dy3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.dy1 = app.t_dy1;
            app.CallingApp.dx1 = app.t_dx1;
            app.CallingApp.dy2 = app.t_dy2;
            app.CallingApp.dx2 = app.t_dx2;
            app.CallingApp.dy3 = app.t_dy3;
            app.CallingApp.dx3 = app.t_dx3;
%             disp([app.Ind,app.CallingApp.dy1,app.CallingApp.dx1,app.CallingApp.dy2,app.CallingApp.dx2,app.CallingApp.dy3,app.CallingApp.dx3])
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.YRowLabel = uilabel(app.GridLayout);
            app.YRowLabel.Text = 'ΔY(Row)';
            app.XColLabel = uilabel(app.GridLayout);
            app.XColLabel.Text = 'ΔX (Col)';
            app.IncreasedYButton = uibutton(app.GridLayout, 'push');
            app.IncreasedYButton.ButtonPushedFcn = createCallbackFcn(app, @IncreasedYButtonPushed, true);
            app.IncreasedYButton.Text = 'Increase dY';
            app.DecreasedYButton = uibutton(app.GridLayout, 'push');
            app.DecreasedYButton.ButtonPushedFcn = createCallbackFcn(app, @DecreasedYButtonPushed, true);
            app.DecreasedYButton.Text = 'Decrease dY';
            app.IncreasedXButton = uibutton(app.GridLayout, 'push');
            app.IncreasedXButton.ButtonPushedFcn = createCallbackFcn(app, @IncreasedXButtonPushed, true);
            app.IncreasedXButton.Text = 'Increase dX';
            app.DecreasedXButton = uibutton(app.GridLayout, 'push');
            app.DecreasedXButton.ButtonPushedFcn = createCallbackFcn(app, @DecreasedXButtonPushed, true);
            app.DecreasedXButton.Text = 'Decrease dX';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTickLabel = '';
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_6.YTickLabel = '';
            app.SimulatedPSFLabel = uilabel(app.GridLayout);
            app.SimulatedPSFLabel.Text = 'Simulated PSF';
            app.ExperimentalPSFLabel = uilabel(app.GridLayout);
            app.ExperimentalPSFLabel.Text = 'Experimental PSF';
            app.UIAxes_7 = uiaxes(app.GridLayout);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_7.YTickLabel = '';
            app.UIAxes_8 = uiaxes(app.GridLayout);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_8.XTickLabel = '';
            app.UIAxes_8.YTickLabel = '';
            app.UIAxes_9 = uiaxes(app.GridLayout);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_9.XTickLabel = '';
            app.UIAxes_9.YTickLabel = '';
            app.OverlappingViewLabel = uilabel(app.GridLayout);
            app.OverlappingViewLabel.Text = 'Overlapping View';
            app.ShowLayerButton = uibutton(app.GridLayout, 'push');
            app.ShowLayerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLayerButtonPushed, true);
            app.ShowLayerButton.Text = 'Show Layer';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Ind'; 'dy1'; 'dx1'; 'dy2'; 'dx2'; 'dy3'; 'dx3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.PlotDisplacementButton = uibutton(app.GridLayout, 'push');
            app.PlotDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDisplacementButtonPushed, true);
            app.PlotDisplacementButton.Text = 'Plot Displacement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualAli(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end