classdef PSFCorrManual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SimulatedPSFLabel               matlab.ui.control.Label
        ExperimentalPSFLabel            matlab.ui.control.Label
        Get3ROIsButton                  matlab.ui.control.Button
        Get3ROIsButton_2                matlab.ui.control.Button
        BackwardButton                  matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        OpenSimulatedPSFFolderButton    matlab.ui.control.Button
        OpenExperimentalPSFFolderButton  matlab.ui.control.Button
        STARTROUGHALIGNINGButton        matlab.ui.control.Button
        SimExpFrameNoLabel              matlab.ui.control.Label
        SaveFolderButton                matlab.ui.control.Button
        SavePSFLabel                    matlab.ui.control.Label
        ExportVarsButton                matlab.ui.control.Button
        ShowROIsButton                  matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        GetAligningDisplacementButton   matlab.ui.control.Button
        LoadOffsetButton                matlab.ui.control.Button
        ManualAligningButton            matlab.ui.control.Button
        FineTuneResizingScaleLabel      matlab.ui.control.Label
        FineTuneResizingScaleEditField  matlab.ui.control.NumericEditField
        GetCentroidsButton              matlab.ui.control.Button
        FineTuneButton                  matlab.ui.control.Button
        STARTFINEALIGNINGButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        initialized =0;
        initialized2 =0;
        selpath = '.'; % Open directory
        selpath2 = '.'; % Open directory
        savepath = '.';
        
        tifnum = 0;
        tifnames = [];
        tifind = [];
        
        tifnum2 = 0;
        tifnames2 = [];
        tifind2 = [];
        
        SimExpInd = [0,0];
        
        preimg = [];
        preimg2 = [];
        
        ROI = [];
        ROI2 = [];
        ROIboth = [];
        
        alignind = [];
        dx1 = []; dy1 = [];
        dx2 = []; dy2 = [];
        dx3 = []; dy3 = [];
        DXY = [];
        
        dispApp = [];
        showFeatureApp = [];
        manualAli = [];
        
        centpos_re = [];
        dcentpos = [];
    end
    
    methods (Access = private)
        
        function [posx,posy] = imcentroid(app,iminput)
            [row,col] = size(iminput);
            [X,Y] = meshgrid(1:1:col,1:1:row);
            posx = sum(X.*iminput,'all')/sum(iminput,'all');
            posy = sum(Y.*iminput,'all')/sum(iminput,'all');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSimulatedPSFFolderButton
        function OpenSimulatedPSFFolderButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.selpath = 'Z:\Xuanwen\HRLF\GTData';
                    NNN = dir(app.selpath);
                catch
                    app.selpath = '.\';
                end
            end
            selpath0 = uigetdir(app.selpath);
            if length(char(selpath0))~=1
                app.selpath = selpath0;
                app.savepath = selpath0;
                try
                    % disp(['Open Directory: ',app.selpath])
                    %                     S = split(app.selpath,'\');
                    subfolder = dir(app.selpath);
                    app.tifnum = 0;
                    app.tifnames = [];
                    app.tifind = [];
                    for i = 3:length(subfolder)
                        filename = subfolder(i).name;
                        if strcmp(filename(end-3:end),'.mat')
                            app.tifnum = app.tifnum + 1;
                            app.tifnames = cat(1,app.tifnames,string(filename));
                            app.tifind = cat(1,app.tifind,str2num(filename(isstrprop(filename,'digit'))));
                        end
                    end
                    %                     app.tifind = app.tifind;
                    %                     disp(app.tifnames)
                    app.SimulatedPSFLabel.Text = app.selpath;
                    app.SimExpInd(1) = 50000;
                    app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                    %                     disp(size(app.tifind))
                    %                     disp([char(app.selpath),'\',char(app.tifnames(app.tifind==50000))])
                    load([char(app.selpath),'\',char(app.tifnames(app.tifind==50000))],'PSF');
                    %                     disp('Simulated PSF got!')
                    app.preimg = PSF;
                    app.preimg = abs(app.preimg).^2;
                    app.preimg = app.preimg/max(app.preimg(:));
                    imagesc(app.preimg,'Parent',app.UIAxes);
                catch
                    
                end
            end
            app.initialized = 1;
        end

        % Button pushed function: OpenExperimentalPSFFolderButton
        function OpenExperimentalPSFFolderButtonPushed(app, event)
            if app.initialized2 ==0
                try
                    app.selpath2 = 'Z:\Xuanwen\HRLF\GTData';
                    NNN = dir(app.selpath2);
                catch
                    app.selpath2 = '.\';
                end
            end
            selpath0 = uigetdir(app.selpath2);
            if length(char(selpath0))~=1
                app.selpath2 = selpath0;
                %                 app.savepath = selpath0;
                %                 app.savepath = selpath0;
                try
                    % disp(['Open Directory: ',app.selpath])
                    %                     S = split(app.selpath2,'\');
                    subfolder = dir(app.selpath2);
                    app.tifnum2 = 0;
                    app.tifnames2 = [];
                    app.tifind2 = [];
                    for i = 3:length(subfolder)
                        filename = subfolder(i).name;
                        if strcmp(filename(end-3:end),'.tif')
                            app.tifnum2 = app.tifnum2 + 1;
                            app.tifnames2 = cat(1,app.tifnames2,string(filename));
                            app.tifind2 = cat(1,app.tifind2,str2num(filename(isstrprop(filename,'digit'))));
                        end
                    end
                    %                     app.tifind2 = app.tifind2;
                    %                     disp(app.tifnames2)
                    app.ExperimentalPSFLabel.Text = app.selpath2;
                    app.SimExpInd(2) = 50000;
                    app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                    %                     disp([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==50000))]);
                    app.preimg2 = double(imread([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==50000))]));
                    app.preimg2 = app.preimg2/max(app.preimg2(:));
                    imagesc(double(app.preimg2),'Parent',app.UIAxes_2);
                    %                     disp('Image displayed!')
                catch
                end
            end
            app.initialized2 = 1;
        end

        % Button pushed function: SaveFolderButton
        function SaveFolderButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.savepath = 'Z:\Xuanwen\HRLF\GTData';
                    NNN = dir(app.savepath);
                catch
                    app.savepath = '.\';
                end
            end
            savepath0 = uigetdir(app.savepath);
            if length(char(savepath0))~=1
                app.savepath = savepath0;
                %                 app.selpath = savepath0;
                try
                    % disp(['Save Directory: ',app.savepath])
                    %                     S = split(app.savepath,'\');
                    %                     subfolder = dir(app.savepath);
                    %                     for i = 3:length(subfolder)
                    %                         uitreenode(uitreepath,'Text',subfolder(i).name);
                    %                     end
                    %                     expand(app.Tree2,'all')
                    app.SavePSFLabel.Text = app.savepath;
                catch
                    
                end
            end
            app.initialized = 1;
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            try
                app.BackwardButton.Enable = true;
                app.ForwardButton.Enable = true;
                app.SimExpInd = app.SimExpInd-1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                %                 if app.SimExpInd >= max(app.tifind(1),app.tifind2(1)) && app.SimExpInd <= min(app.tifind(end),app.tifind2(end))
                
                load([char(app.selpath),'\',char(app.tifnames(app.tifind==app.SimExpInd(1)))],'PSF');
                app.preimg = PSF;
                app.preimg = abs(app.preimg).^2;
                app.preimg = app.preimg/max(app.preimg(:));
                imagesc(app.preimg,'Parent',app.UIAxes);
                
                app.preimg2 = double(imread([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==app.SimExpInd(2)))]));
                app.preimg2 = app.preimg2/max(app.preimg2(:));
                imagesc(app.preimg2,'Parent',app.UIAxes_2);
                %                 end
            catch
                app.BackwardButton.Enable = false;
                app.SimExpInd = app.SimExpInd+1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            try
                app.BackwardButton.Enable = true;
                app.ForwardButton.Enable = true;
                app.SimExpInd = app.SimExpInd+1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                
                load([char(app.selpath),'\',char(app.tifnames(app.tifind==app.SimExpInd(1)))],'PSF');
                app.preimg = PSF;
                app.preimg = abs(app.preimg).^2;
                app.preimg = app.preimg/max(app.preimg(:));
                imagesc(app.preimg,'Parent',app.UIAxes);
                
                app.preimg2 = double(imread([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==app.SimExpInd(2)))]));
                app.preimg2 = app.preimg2/max(app.preimg2(:));
                imagesc(app.preimg2,'Parent',app.UIAxes_2);
            catch
                app.ForwardButton.Enable = false;
                app.SimExpInd = app.SimExpInd-1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
            end
        end

        % Button pushed function: Get3ROIsButton
        function Get3ROIsButtonPushed(app, event)
            try
                figure(1),imagesc(app.preimg);
                [posx,posy] = ginput(3);
                
                sq1 = app.preimg(posy(1)-25:posy(1)+25,posx(1)-25:posx(1)+25);
                sq2 = app.preimg(posy(2)-25:posy(2)+25,posx(2)-25:posx(2)+25);
                sq3 = app.preimg(posy(3)-25:posy(3)+25,posx(3)-25:posx(3)+25);
                [maxrow1,maxcol1] = getMax(sq1,26,26);
                [maxrow2,maxcol2] = getMax(sq2,26,26);
                [maxrow3,maxcol3] = getMax(sq3,26,26);
                maxrow1 = round(maxrow1 + posy(1)-26);
                maxcol1 = round(maxcol1 + posx(1)-26);
                maxrow2 = round(maxrow2 + posy(2)-26);
                maxcol2 = round(maxcol2 + posx(2)-26);
                maxrow3 = round(maxrow3 + posy(3)-26);
                maxcol3 = round(maxcol3 + posx(3)-26);
                
                app.ROI = [maxrow1-100,maxcol1-100,maxrow1+100,maxcol1+100;
                    maxrow2-100,maxcol2-100,maxrow2+100,maxcol2+100;
                    maxrow3-100,maxcol3-100,maxrow3+100,maxcol3+100];
            catch
            end
        end

        % Button pushed function: Get3ROIsButton_2
        function Get3ROIsButton_2Pushed(app, event)
            try
                figure(2),imagesc(app.preimg2);
                [posx,posy] = ginput(3);
                
                sq1 = app.preimg2(posy(1)-25:posy(1)+25,posx(1)-25:posx(1)+25);
                sq2 = app.preimg2(posy(2)-25:posy(2)+25,posx(2)-25:posx(2)+25);
                sq3 = app.preimg2(posy(3)-25:posy(3)+25,posx(3)-25:posx(3)+25);
                [maxrow1,maxcol1] = getMax(sq1,26,26);
                [maxrow2,maxcol2] = getMax(sq2,26,26);
                [maxrow3,maxcol3] = getMax(sq3,26,26);
                maxrow1 = round(maxrow1 + posy(1)-26);
                maxcol1 = round(maxcol1 + posx(1)-26);
                maxrow2 = round(maxrow2 + posy(2)-26);
                maxcol2 = round(maxcol2 + posx(2)-26);
                maxrow3 = round(maxrow3 + posy(3)-26);
                maxcol3 = round(maxcol3 + posx(3)-26);
                
                app.ROI2 = [maxrow1-100,maxcol1-100,maxrow1+100,maxcol1+100;
                    maxrow2-100,maxcol2-100,maxrow2+100,maxcol2+100;
                    maxrow3-100,maxcol3-100,maxrow3+100,maxcol3+100];
            catch
            end
            
        end

        % Button pushed function: ShowROIsButton
        function ShowROIsButtonPushed(app, event)
            load([char(app.selpath),'\',char(app.tifnames(app.tifind==app.SimExpInd(1)))],'PSF');
            app.preimg = PSF;
            app.preimg = abs(app.preimg).^2;
            app.preimg = app.preimg/max(app.preimg(:));
            imagesc(app.preimg,'Parent',app.UIAxes);
            
            app.preimg2 = double(imread([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==app.SimExpInd(2)))]));
            app.preimg2 = app.preimg2/max(app.preimg2(:));
            imagesc(app.preimg2,'Parent',app.UIAxes_2);
            
            disp(app.ROI)
            disp(app.ROI2)
            
            app.ROIboth = [min(app.ROI(:,1),app.ROI2(:,1)),...
                min(app.ROI(:,2),app.ROI2(:,2)),...
                max(app.ROI(:,3),app.ROI2(:,3)),...
                max(app.ROI(:,4),app.ROI2(:,4))];
            disp(app.ROIboth)
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,1)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,1)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,1)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = 1;
            
            app.preimg(app.ROIboth(1,3)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg(app.ROIboth(2,3)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg(app.ROIboth(3,3)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = 1;
            
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,2)+2) = 1;
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,2)+2) = 1;
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,2)+2) = 1;
            
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,4)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,4)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,4)-2:app.ROIboth(3,4)+2) = 1;
            % ===========================================================================
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,1)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,1)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,1)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = 1;
            
            app.preimg2(app.ROIboth(1,3)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg2(app.ROIboth(2,3)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg2(app.ROIboth(3,3)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = 1;
            
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,2)+2) = 1;
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,2)+2) = 1;
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,2)+2) = 1;
            
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,4)-2:app.ROIboth(1,4)+2) = 1;
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,4)-2:app.ROIboth(2,4)+2) = 1;
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,4)-2:app.ROIboth(3,4)+2) = 1;
            % ===========================================================================
            imagesc(app.preimg,'Parent',app.UIAxes);
            imagesc(app.preimg2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsButtonPushed(app, event)
            
            labels = {'Save sim. PSF opened directory to variable named:',...
                'Save exp. PSF opened directory to variable named:',...
                'Save saving directory to variable named:',...
                'Save sim. tif numbers to variable named:',...
                'Save sim. tif filenames to variable named:',...
                'Save sim. tif index to variable named:',...
                'Save exp. tif numbers to variable named:',...
                'Save exp. tif filenames to variable named:',...
                'Save exp. tif index to variable named:',...
                'Save sim. & exp. indeces to variable named:',...
                'Save preview sim. image to variable named:',...
                'Save preview exp. image to variable named:',...
                'Save sim. ROI to variable named:',...
                'Save exp. ROI to variable named:',...
                'Save integrated ROI to variable named:',...
                'Save indeces to align to variable named:',...
                'Save Offsets to variable named:'};
            vars = {'selpath','selpath2','savepath','tifnum','tifnames','tifind','tifnum2','tifnames2','tifind2',...
                'SimExpInd','preimg','preimg2','ROI','ROI2','ROIboth','alignind','Offset'};
            xyOffset = [app.dy1,app.dx1,app.dy2,app.dx2,app.dy3,app.dx3];
            values = {app.selpath,app.selpath2,app.savepath,app.tifnum,app.tifnames,app.tifind,app.tifnum2,...
                app.tifnames2,app.tifind2,app.SimExpInd,app.preimg,app.preimg2,app.ROI,app.ROI2,app.ROIboth,app.alignind,xyOffset};
            export2wsdlg(labels,vars,values);
        end

        % Button pushed function: STARTROUGHALIGNINGButton
        function STARTROUGHALIGNINGButtonPushed(app, event)
            app.StatusLabel.Text = 'Aligning...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            try
                app.STARTROUGHALIGNINGButton.Enable = false;
                for ii = app.tifind'
                    
                    load([char(app.selpath),'\',char(app.tifnames(app.tifind==ii))],'PSF');
                    %                     simimg = PSF;
                    %                     simimg = abs(simimg).^2;
                    %                     simimg = simimg/max(simimg(:));
                    try
                        disp([app.alignind(app.alignind==ii),app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)])

                        PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)) = ...
                            circshift(PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),...
                            [app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)]);
                        PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)) = ...
                            circshift(PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),...
                            [app.dy2(app.alignind==ii),app.dx2(app.alignind==ii)]);
                        PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)) = ...
                            circshift(PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),...
                            [app.dy3(app.alignind==ii),app.dx3(app.alignind==ii)]);
                    catch
                        app.StatusLabel.Text = ['Aligning Excluded! [',num2str(ii),']'];
                        app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                    end
                    %                     PSF = simimg;
                    save([char(app.savepath),'\',char(app.tifnames(app.tifind==ii))],'PSF')
                    app.StatusLabel.Text = ['Aligning... [',num2str(ii),']'];
                    drawnow;
                end
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                drawnow;
            catch ME
                MEstack = ME.stack(3);
                %                 disp(MEstack.line)
                app.StatusLabel.Text = ['[',num2str(MEstack.line),']',ME.message];
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.STARTROUGHALIGNINGButton.Enable = true;
                rethrow(ME)
            end
            app.STARTROUGHALIGNINGButton.Enable = true;
        end

        % Button pushed function: GetAligningDisplacementButton
        function GetAligningDisplacementButtonPushed(app, event)
            app.StatusLabel.Text = 'Getting Aligning Displacement...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            [optmizer, metric] = imregconfig('multimodal');
            optmizer.InitialRadius = optmizer.InitialRadius/2;
            optmizer.MaximumIterations = 500;
            try
                app.GetAligningDisplacementButton.Enable = false;
                drawnow;
                app.alignind = [];
                app.dx1 = []; app.dy1 = [];
                app.dx2 = []; app.dy2 = [];
                app.dx3 = []; app.dy3 = [];
                for ii = app.tifind'
                    try
                        load([char(app.selpath),'\',char(app.tifnames(app.tifind==ii))],'PSF');
                        simimg = PSF;
                        simimg = abs(simimg).^2;
                        simimg = simimg/max(simimg(:));
                        
                        expimg = double(imread([char(app.selpath2),'\',char(app.tifnames2(app.tifind2==ii+app.SimExpInd(2)-app.SimExpInd(1)))]));
                        expimg = expimg/max(expimg(:));
                        
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 1/3]'];
                        drawnow;
                        [dxi1,dyi1] = AliDispFunc(simimg(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),...
                            expimg(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),optmizer, metric);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 2/3]'];
                        drawnow;
                        [dxi2,dyi2] = AliDispFunc(simimg(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),...
                            expimg(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),optmizer, metric);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 3/3]'];
                        drawnow;
                        [dxi3,dyi3] = AliDispFunc(simimg(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),...
                            expimg(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),optmizer, metric);
                        
                        app.dx1 = cat(1,app.dx1,dxi1);
                        app.dy1 = cat(1,app.dy1,dyi1);
                        app.dx2 = cat(1,app.dx2,dxi2);
                        app.dy2 = cat(1,app.dy2,dyi2);
                        app.dx3 = cat(1,app.dx3,dxi3);
                        app.dy3 = cat(1,app.dy3,dyi3);
                        %                         disp(ii)
                        app.alignind = cat(1,app.alignind,ii);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),']'];
                        drawnow;
                    catch ME
                        if (ii+app.SimExpInd(2)-app.SimExpInd(1))<app.tifind2(1) || (ii+app.SimExpInd(2)-app.SimExpInd(1))>app.tifind2(end)
                            app.StatusLabel.Text = ['Out of Range - Ignored... [',num2str(ii),']'];
                            drawnow;
                        else
                            rethrow(ME)
                        end
                    end
                end
                %                 disp([size(app.alignind);size(dx1);size(dy1);size(dx2);size(dy2);size(dx3);size(dy3)])
                %                 disp(dx1((app.alignind>=48000) & (app.alignind<=52000)))
                %                 app.DXY = [mean(app.dx1(app.tifind>=48000 & app.tifind<=52000)),mean(app.dy1(app.tifind>=48000 & app.tifind<=52000));
                %                            mean(app.dx2(app.tifind>=48000 & app.tifind<=52000)),mean(app.dy2(app.tifind>=48000 & app.tifind<=52000));
                %                            mean(app.dx3(app.tifind>=48000 & app.tifind<=52000)),mean(app.dy3(app.tifind>=48000 & app.tifind<=52000))];
                %                 app.dispApp = AliDisp(app,app.alignind,app.dx1,app.dy1,app.dx2,app.dy2,app.dx3,app.dy3);
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                app.GetAligningDisplacementButton.Enable = true;
                app.STARTROUGHALIGNINGButton.Enable = true;
            catch ME
                app.StatusLabel.Text = 'ERROR!';
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.GetAligningDisplacementButton.Enable = true;
                rethrow(ME);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.dispApp)
            catch
            end
            try
                delete(app.showFeatureApp)
            catch
            end
            delete(app)
            
        end

        % Button pushed function: LoadOffsetButton
        function LoadOffsetButtonPushed(app, event)
            [OffsetFile,OffsetPath] = uigetfile(app.selpath);
            if length(char(OffsetFile))~=1
                try
                    app.StatusLabel.Text = 'Loading Offset file...';
                    app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
                    drawnow;
                    load(fullfile(OffsetPath,OffsetFile),"-mat",'Offset');
                    app.dy1 = Offset(:,1);
                    app.dx1 = Offset(:,2);
                    app.dy2 = Offset(:,3);
                    app.dx2 = Offset(:,4);
                    app.dy3 = Offset(:,5);
                    app.dx3 = Offset(:,6);
                    
                catch ME
                    rethrow(ME);
                end
                app.StatusLabel.Text = 'Offset file loaded!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                drawnow;
            end
            
        end

        % Button pushed function: ManualAligningButton
        function ManualAligningButtonPushed(app, event)
            app.manualAli = ManualAli(app,app.preimg,app.preimg2,app.ROIboth);
        end

        % Button pushed function: GetCentroidsButton
        function GetCentroidsButtonPushed(app, event)
            app.StatusLabel.Text = ['Getting Centroids...'];
            drawnow;
            positions = [];
            for ii = app.tifind'
                try
                    load([char(app.selpath),'\',char(app.tifnames(app.tifind==ii))],'PSF');
                    simimg = PSF;
                    simimg = abs(simimg).^2;
                    simimg = simimg/max(simimg(:));
                    sec1 = simimg(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4));
                    sec2 = simimg(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4));
                    sec3 = simimg(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4));
                    [posx1,posy1] = app.imcentroid(sec1);
                    [posx2,posy2] = app.imcentroid(sec2);
                    [posx3,posy3] = app.imcentroid(sec3);
                    positions = cat(1,positions,...
                        [posx1+app.ROIboth(1,2),posy1+app.ROIboth(1,1),...
                        posx2+app.ROIboth(2,2),posy2+app.ROIboth(2,1),...
                        posx3+app.ROIboth(3,2),posy3+app.ROIboth(3,1)]);
                    disp([ii,round([posx1+app.ROIboth(1,2),posy1+app.ROIboth(1,1),...
                        posx2+app.ROIboth(2,2),posy2+app.ROIboth(2,1),...
                        posx3+app.ROIboth(3,2),posy3+app.ROIboth(3,1)])])
                    app.StatusLabel.Text = ['Getting Centroids ',num2str(ii),' done!'];
                    drawnow;
                catch ME
                    if (ii+app.SimExpInd(2)-app.SimExpInd(1))<app.tifind2(1) || (ii+app.SimExpInd(2)-app.SimExpInd(1))>app.tifind2(end)
                        app.StatusLabel.Text = ['Out of Range - Ignored... [',num2str(ii),']'];
                        drawnow;
                    else
                        rethrow(ME)
                    end
                end
            end
            disp(positions(app.tifind==50100,:))
            app.centpos_re = (positions - positions(app.tifind==50100,:));
        end

        % Button pushed function: FineTuneButton
        function FineTuneButtonPushed(app, event)
            p1 = polyfit(app.tifind,app.centpos_re(:,1),1);
            p2 = polyfit(app.tifind,app.centpos_re(:,2),1);
            p3 = polyfit(app.tifind,app.centpos_re(:,3),1);
            p4 = polyfit(app.tifind,app.centpos_re(:,4),1);
            p5 = polyfit(app.tifind,app.centpos_re(:,5),1);
            p6 = polyfit(app.tifind,app.centpos_re(:,6),1);
            app.dcentpos = [(p1(1)*app.tifind + p1(2))-app.centpos_re(:,1),...
                            (p2(1)*app.tifind + p2(2))-app.centpos_re(:,2),...
                            (p3(1)*app.tifind + p3(2))-app.centpos_re(:,3),...
                            (p4(1)*app.tifind + p4(2))-app.centpos_re(:,4),...
                            (p5(1)*app.tifind + p5(2))-app.centpos_re(:,5),...
                            (p6(1)*app.tifind + p6(2))-app.centpos_re(:,6)];
            app.dcentpos = round(app.dcentpos * app.FineTuneResizingScaleEditField.Value);
            figure(6),plot(app.centpos_re);
            figure(66),plot((app.dcentpos));
        end

        % Button pushed function: STARTFINEALIGNINGButton
        function STARTFINEALIGNINGButtonPushed(app, event)
            app.StatusLabel.Text = 'Aligning...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            try
                app.STARTROUGHALIGNINGButton.Enable = false;
                for ii = app.tifind'
                    
                    load([char(app.selpath),'\',char(app.tifnames(app.tifind==ii))],'PSF');
                    %                     simimg = PSF;
                    %                     simimg = abs(simimg).^2;
                    %                     simimg = simimg/max(simimg(:));
                    try
                        disp([app.alignind(app.alignind==ii),app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)])
   
                        PSF1 =  PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4));
                        PSF1 = imresize(PSF1,app.FineTuneResizingScaleEditField.Value);
                        PSF1 = circshift(PSF1,[app.dcentpos(app.tifind==ii,2),app.dcentpos(app.tifind==ii,1)]);
                        PSF1 = imresize(PSF1,1.0 / app.FineTuneResizingScaleEditField.Value);
                        PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)) = PSF1;
                        
                        PSF2 =  PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4));
                        PSF2 = imresize(PSF2,app.FineTuneResizingScaleEditField.Value);
                        PSF2 = circshift(PSF2,[app.dcentpos(app.tifind==ii,4),app.dcentpos(app.tifind==ii,3)]);
                        PSF2 = imresize(PSF2,1.0 / app.FineTuneResizingScaleEditField.Value);
                        PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)) = PSF2;
                        
                        PSF3 =  PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4));
                        PSF3 = imresize(PSF3,app.FineTuneResizingScaleEditField.Value);
                        PSF3 = circshift(PSF3,[app.dcentpos(app.tifind==ii,6),app.dcentpos(app.tifind==ii,5)]);
                        PSF3 = imresize(PSF3,1.0 / app.FineTuneResizingScaleEditField.Value);
                        PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)) = PSF3;
                    catch
                        app.StatusLabel.Text = ['Aligning Excluded! [',num2str(ii),']'];
                        app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                    end
                    %                     PSF = simimg;
                    save([char(app.savepath),'\',char(app.tifnames(app.tifind==ii))],'PSF')
                    app.StatusLabel.Text = ['Aligning... [',num2str(ii),']'];
                    drawnow;
                end
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                drawnow;
            catch ME
                MEstack = ME.stack(3);
                %                 disp(MEstack.line)
                app.StatusLabel.Text = ['[',num2str(MEstack.line),']',ME.message];
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.STARTROUGHALIGNINGButton.Enable = true;
                rethrow(ME)
            end
            app.STARTROUGHALIGNINGButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SimulatedPSFLabel = uilabel(app.GridLayout);
            app.SimulatedPSFLabel.Text = 'Simulated PSF';
            app.ExperimentalPSFLabel = uilabel(app.GridLayout);
            app.ExperimentalPSFLabel.Text = 'Experimental PSF';
            app.Get3ROIsButton = uibutton(app.GridLayout, 'push');
            app.Get3ROIsButton.ButtonPushedFcn = createCallbackFcn(app, @Get3ROIsButtonPushed, true);
            app.Get3ROIsButton.Text = 'Get 3 ROIs';
            app.Get3ROIsButton_2 = uibutton(app.GridLayout, 'push');
            app.Get3ROIsButton_2.ButtonPushedFcn = createCallbackFcn(app, @Get3ROIsButton_2Pushed, true);
            app.Get3ROIsButton_2.Text = 'Get 3 ROIs';
            app.BackwardButton = uibutton(app.GridLayout, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = '◀ Backward';
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward ▶';
            app.OpenSimulatedPSFFolderButton = uibutton(app.GridLayout, 'push');
            app.OpenSimulatedPSFFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulatedPSFFolderButtonPushed, true);
            app.OpenSimulatedPSFFolderButton.Text = 'Open Simulated PSF Folder';
            app.OpenExperimentalPSFFolderButton = uibutton(app.GridLayout, 'push');
            app.OpenExperimentalPSFFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExperimentalPSFFolderButtonPushed, true);
            app.OpenExperimentalPSFFolderButton.Text = 'Open Experimental PSF Folder';
            app.STARTROUGHALIGNINGButton = uibutton(app.GridLayout, 'push');
            app.STARTROUGHALIGNINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTROUGHALIGNINGButtonPushed, true);
            app.STARTROUGHALIGNINGButton.Text = 'START ROUGH ALIGNING';
            app.SimExpFrameNoLabel = uilabel(app.GridLayout);
            app.SimExpFrameNoLabel.Text = 'Sim-Exp Frame No';
            app.SaveFolderButton = uibutton(app.GridLayout, 'push');
            app.SaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFolderButtonPushed, true);
            app.SaveFolderButton.Text = 'Save Folder';
            app.SavePSFLabel = uilabel(app.GridLayout);
            app.SavePSFLabel.Text = 'Save PSF';
            app.ExportVarsButton = uibutton(app.GridLayout, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.ShowROIsButton = uibutton(app.GridLayout, 'push');
            app.ShowROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowROIsButtonPushed, true);
            app.ShowROIsButton.Text = 'Show ROIs';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            app.GetAligningDisplacementButton = uibutton(app.GridLayout, 'push');
            app.GetAligningDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @GetAligningDisplacementButtonPushed, true);
            app.GetAligningDisplacementButton.Text = 'Get Aligning Displacement';
            app.LoadOffsetButton = uibutton(app.GridLayout, 'push');
            app.LoadOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOffsetButtonPushed, true);
            app.LoadOffsetButton.Text = 'Load Offset';
            app.ManualAligningButton = uibutton(app.GridLayout, 'push');
            app.ManualAligningButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAligningButtonPushed, true);
            app.ManualAligningButton.Text = 'Manual Aligning';
            app.FineTuneResizingScaleLabel = uilabel(app.GridLayout);
            app.FineTuneResizingScaleLabel.Text = 'Fine Tune Resizing Scale: ';
            app.FineTuneResizingScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.FineTuneResizingScaleEditField.Value = 10;
            app.GetCentroidsButton = uibutton(app.GridLayout, 'push');
            app.GetCentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @GetCentroidsButtonPushed, true);
            app.GetCentroidsButton.Text = 'Get Centroids';
            app.FineTuneButton = uibutton(app.GridLayout, 'push');
            app.FineTuneButton.ButtonPushedFcn = createCallbackFcn(app, @FineTuneButtonPushed, true);
            app.FineTuneButton.Text = 'Fine Tune';
            app.STARTFINEALIGNINGButton = uibutton(app.GridLayout, 'push');
            app.STARTFINEALIGNINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTFINEALIGNINGButtonPushed, true);
            app.STARTFINEALIGNINGButton.Text = 'START FINE ALIGNING';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.GridLayout);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSFCorrManual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end