classdef showFeature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        UIAxes      matlab.ui.control.UIAxes
        UIAxes_2    matlab.ui.control.UIAxes
        UIAxes_3    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, original, distorted, matchedOriginal, matchedDistorted)
            app.CallingApp = mainapp;
            showMatchedFeatures(original(:,:,1), distorted(:,:,1), matchedOriginal(:,:,1), matchedDistorted(:,:,1),'montage','Parent',app.UIAxes);
            showMatchedFeatures(original(:,:,2), distorted(:,:,2), matchedOriginal(:,:,2), matchedDistorted(:,:,2),'montage','Parent',app.UIAxes_2);
            showMatchedFeatures(original(:,:,2), distorted(:,:,3), matchedOriginal(:,:,3), matchedDistorted(:,:,3),'montage','Parent',app.UIAxes_3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GetAligningDisplacementSURFButton.Enable = true;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'ROI-1')
            xlabel(app.UIAxes, 'Axial Positions')
            ylabel(app.UIAxes, 'X-Y Displacement')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'ROI-2')
            xlabel(app.UIAxes_2, 'Axial Positions')
            ylabel(app.UIAxes_2, 'X-Y Displacement')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'ROI-3')
            xlabel(app.UIAxes_3, 'Axial Positions')
            ylabel(app.UIAxes_3, 'X-Y Displacement')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showFeature(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end